classdef gui < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure               matlab.ui.Figure
        GridLayout             matlab.ui.container.GridLayout
        LeftPanel              matlab.ui.container.Panel
        Spinner2               matlab.ui.control.Spinner
        Spinner2Label          matlab.ui.control.Label
        DriveSpeedSlider       matlab.ui.control.Slider
        DriveSpeedSliderLabel  matlab.ui.control.Label
        CenterPanel            matlab.ui.container.Panel
        UITable                matlab.ui.control.Table
        CarIDSpinner           matlab.ui.control.Spinner
        CarIDSpinnerLabel      matlab.ui.control.Label
        RightPanel             matlab.ui.container.Panel
        Gauge                  matlab.ui.control.Gauge
        GaugeLabel             matlab.ui.control.Label
    end

    % Properties that correspond to apps with auto-reflow
    properties (Access = private)
        onePanelWidth = 576;
        twoPanelWidth = 768;
    end


    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
           clear all
           clc
           global delayer;

           rc_ml;
           delayer = Delay();
           delayer.delay_ms(16.6667)
           
        end

        % Callback function: DriveSpeedSlider, Spinner2
        function DriveSpeedSliderValueChanging(app, event)
            global delayer;

            changingValue = event.Value;
            disp(changingValue)
            caller = strsplit(class(event.Source),".");
            caller_name = caller{4};
           if strcmp(caller_name,'Slider')
              app.Spinner2.Value = changingValue;
           else                
              app.DriveSpeedSlider.Value = changingValue;
           end
           if delayer.timeout()
            move_motors(0,0,round(changingValue))
            delayer.delay_ms(16.667)
           end


        end

        % Callback function
        function Spinner2ValueChanged(app, event)

        end

        % Changes arrangement of the app based on UIFigure width
        function updateAppLayout(app, event)
            currentFigureWidth = app.UIFigure.Position(3);
            if(currentFigureWidth <= app.onePanelWidth)
                % Change to a 3x1 grid
                app.GridLayout.RowHeight = {480, 480, 480};
                app.GridLayout.ColumnWidth = {'1x'};
                app.CenterPanel.Layout.Row = 1;
                app.CenterPanel.Layout.Column = 1;
                app.LeftPanel.Layout.Row = 2;
                app.LeftPanel.Layout.Column = 1;
                app.RightPanel.Layout.Row = 3;
                app.RightPanel.Layout.Column = 1;
            elseif (currentFigureWidth > app.onePanelWidth && currentFigureWidth <= app.twoPanelWidth)
                % Change to a 2x2 grid
                app.GridLayout.RowHeight = {480, 480};
                app.GridLayout.ColumnWidth = {'1x', '1x'};
                app.CenterPanel.Layout.Row = 1;
                app.CenterPanel.Layout.Column = [1,2];
                app.LeftPanel.Layout.Row = 2;
                app.LeftPanel.Layout.Column = 1;
                app.RightPanel.Layout.Row = 2;
                app.RightPanel.Layout.Column = 2;
            else
                % Change to a 1x3 grid
                app.GridLayout.RowHeight = {'1x'};
                app.GridLayout.ColumnWidth = {228, '1x', 220};
                app.LeftPanel.Layout.Row = 1;
                app.LeftPanel.Layout.Column = 1;
                app.CenterPanel.Layout.Row = 1;
                app.CenterPanel.Layout.Column = 2;
                app.RightPanel.Layout.Row = 1;
                app.RightPanel.Layout.Column = 3;
            end
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.UIFigure.SizeChangedFcn = createCallbackFcn(app, @updateAppLayout, true);
            app.GridLayout = uigridlayout(app.UIFigure);
            app.LeftPanel = uipanel(app.GridLayout);
            app.DriveSpeedSliderLabel = uilabel(app.LeftPanel);
            app.DriveSpeedSliderLabel.Text = 'Drive Speed';
            app.DriveSpeedSlider = uislider(app.LeftPanel);
            app.DriveSpeedSlider.Limits = [-100 100];
            app.DriveSpeedSlider.ValueChangingFcn = createCallbackFcn(app, @DriveSpeedSliderValueChanging, true);
            app.Spinner2Label = uilabel(app.LeftPanel);
            app.Spinner2Label.Text = 'Spinner2';
            app.Spinner2 = uispinner(app.LeftPanel);
            app.Spinner2.Limits = [-100 100];
            app.Spinner2.ValueChangedFcn = createCallbackFcn(app, @DriveSpeedSliderValueChanging, true);
            app.CenterPanel = uipanel(app.GridLayout);
            app.CarIDSpinnerLabel = uilabel(app.CenterPanel);
            app.CarIDSpinnerLabel.Text = 'Car ID';
            app.CarIDSpinner = uispinner(app.CenterPanel);
            app.UITable = uitable(app.CenterPanel);
            app.UITable.ColumnName = {'Client ID'; 'RSSI'; 'YAW'; 'IMU Status'};
            app.UITable.RowName = {};
            app.RightPanel = uipanel(app.GridLayout);
            app.GaugeLabel = uilabel(app.RightPanel);
            app.GaugeLabel.Text = 'Gauge';
            app.Gauge = uigauge(app.RightPanel, 'circular');
            app.Gauge.Limits = [-180 180];
            app.Gauge.MajorTicks = [-180 -108 -30 30 108 180];
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = gui

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end